classdef face_detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
        UIAxes2   matlab.ui.control.UIAxes
        Button    matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
    end

    
    properties (Access = private)
        OriginalImage 
        Face_detection   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
        [file, path] = uigetfile({'*.jpg;*.png;*.jfif'}, 'Выберите изображение');
            if isequal(file, 0)
                return;
            end
            app.OriginalImage = imread(fullfile(path, file));
            imshow(app.OriginalImage, 'Parent', app.UIAxes);   
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, 'Загрузите изображение', 'Ошибка');
                return;
            end
            faceDetector = vision.CascadeObjectDetector; %Define facedetector object
            faceDetector.MergeThreshold = 20; %Adjust for better detection
            bboxes = faceDetector (app.OriginalImage); %Geting Bounding Box around detected face

            if ~isempty (bboxes)
            app.Face_detection = insertObjectAnnotation (app.OriginalImage, 'rectangle', bboxes, 'Face', 'Linewidth', 3) ;
            
            imshow(uint8(abs(app.Face_detection)), 'Parent', app.UIAxes2);
            app.UIAxes2.Title.String = 'Поиск лиц';
        
            else
                 position=[0 0]; %[x y]
            label='Лицо не найдено';
            app.Face_detection = insertText (app.OriginalImage, position, label, 'FontSize', 25, 'BoxOpacity', 1);
             imshow(uint8(abs(app.Face_detection)), 'Parent', app.UIAxes2);
            app.UIAxes2.Title.String = 'Поиск лиц';
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Исходное изображение')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Найденные лица')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Загрузить изображение';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Найти лица на изображении';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = face_detection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end